classdef Doggo_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        Label           matlab.ui.control.Label
        statusLamp      matlab.ui.control.Lamp
        connect         matlab.ui.control.Button
        CenterPanel     matlab.ui.container.Panel
        sendButton      matlab.ui.control.Button
        SendButton      matlab.ui.control.Button
        SendTextArea    matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        DataLogLabel    matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        RightPanel      matlab.ui.container.Panel
        HopButton       matlab.ui.control.Button
        StopButton      matlab.ui.control.Button
        BoudButton      matlab.ui.control.Button
        TortButton      matlab.ui.control.Button
        WalkButton      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Property % Description
        B        % bluetooth object
        n        % NumBytesAvailable
        Rx_Once  % receive data at once
        Rx_Data  % all of the receive data
        Tx_cellarraytext
        cellarraytext_n
    end
    
    methods (Access = private)
        
        function EveBytesAvailableFcn(app,obj,~)
            app.n = obj.NumBytesAvailable;             % get receive Byte
            app.Rx_Once =  read(app.B,app.n,"string"); % save receive data in cache
            app.Rx_Data = strcat(app.Rx_Data,app.Rx_Once); % merge receive data
            app.TextArea.Value = app.Rx_Data;
            app.Label.Text = num2str(length(app.Rx_Data(1)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Rx_Data = "";
           app.n = 0;
           app.cellarraytext_n = 1;
           app.Label.Text = num2str(app.n);
        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            if app.statusLamp.Enable == false
                app.B = bluetooth("HC-05");
                app.statusLamp.Enable = true;
                app.connect.Text = "Disconnect";
                configureCallback(app.B,"byte",1,@app.EveBytesAvailableFcn);
            else
                delete(app.B);
                app.statusLamp.Enable = false;
                app.connect.Text = "Connect";
                app.n = 0;
                app.Label.Text = num2str(app.n);
            end
            
        end

        % Button pushed function: WalkButton
        function WalkButtonPushed(app, event)
            write(app.B,'W;');
        end

        % Button pushed function: TortButton
        function TortButtonPushed(app, event)
            write(app.B,'T;');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            write(app.B,'S;');
        end

        % Button pushed function: BoudButton
        function BoudButtonPushed(app, event)
            write(app.B,'B;');
        end

        % Button pushed function: HopButton
        function HopButtonPushed(app, event)
            write(app.B,'H;');
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.Tx_cellarraytext{app.cellarraytext_n} = sprintf("%s\n",app.SendTextArea.Value);
            app.cellarraytext_n = app.cellarraytext_n + 1;
            write(app.B,app.SendTextArea.Value);
            if app.sendButton.Text == "receive"
                app.TextArea.Value = horzcat(app.Tx_cellarraytext,string);
            end
        end

        % Button pushed function: sendButton
        function sendButtonPushed(app, event)
            if app.sendButton.Text == "send"
                app.TextArea.Value = horzcat(app.Tx_cellarraytext,string);
                app.sendButton.Text = "receive";
            else
                app.TextArea.Value = app.Rx_Data;
                app.sendButton.Text = "send";
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.connect = uibutton(app.LeftPanel, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = 'Connect';
            app.statusLamp = uilamp(app.LeftPanel);
            app.statusLamp.Enable = 'off';
            app.Label = uilabel(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.CenterPanel);
            app.DataLogLabel = uilabel(app.CenterPanel);
            app.DataLogLabel.Text = {'DataLog'; ''};
            app.EditFieldLabel = uilabel(app.CenterPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.SendTextArea = uieditfield(app.CenterPanel, 'text');
            app.SendButton = uibutton(app.CenterPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.sendButton = uibutton(app.CenterPanel, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButtonPushed, true);
            app.sendButton.Text = 'send';
            app.RightPanel = uipanel(app.GridLayout);
            app.WalkButton = uibutton(app.RightPanel, 'push');
            app.WalkButton.ButtonPushedFcn = createCallbackFcn(app, @WalkButtonPushed, true);
            app.WalkButton.Text = 'Walk';
            app.TortButton = uibutton(app.RightPanel, 'push');
            app.TortButton.ButtonPushedFcn = createCallbackFcn(app, @TortButtonPushed, true);
            app.TortButton.Text = {'Tort'; ''};
            app.BoudButton = uibutton(app.RightPanel, 'push');
            app.BoudButton.ButtonPushedFcn = createCallbackFcn(app, @BoudButtonPushed, true);
            app.BoudButton.Text = {'Boud'; ''};
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.HopButton = uibutton(app.RightPanel, 'push');
            app.HopButton.ButtonPushedFcn = createCallbackFcn(app, @HopButtonPushed, true);
            app.HopButton.Text = 'Hop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doggo_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end